classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        BasicFeaturesTab          matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        Label                     matlab.ui.control.Label
        LoadECGSignalButton       matlab.ui.control.Button
        FindPQRButton             matlab.ui.control.Button
        MICAProjectLabel          matlab.ui.control.Label
        TimeWindowPanel           matlab.ui.container.Panel
        window_beg                matlab.ui.control.NumericEditField
        toLabel                   matlab.ui.control.Label
        window_end                matlab.ui.control.NumericEditField
        ApplyButton               matlab.ui.control.Button
        BradyTachycardiaButton    matlab.ui.control.Button
        BPMLabel                  matlab.ui.control.Label
        EctopicbeatButton         matlab.ui.control.Button
        FibrillationsButton       matlab.ui.control.Button
        ResultLabel               matlab.ui.control.Label
        BPMLabel_2                matlab.ui.control.Label
        InverseECGSignalButton    matlab.ui.control.Button
        AdvancedFeaturesTab       matlab.ui.container.Tab
        Label_2                   matlab.ui.control.Label
        MICAProjectLabel_2        matlab.ui.control.Label
        LoadECGSignalButton_2     matlab.ui.control.Button
        BandPassButton            matlab.ui.control.Button
        DerivativeButton          matlab.ui.control.Button
        MWIButton                 matlab.ui.control.Button
        InverseECGSignalButton_2  matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        SignalSpectrumButton      matlab.ui.control.Button
        SampleWindowPanel         matlab.ui.container.Panel
        window_beg_2              matlab.ui.control.NumericEditField
        toLabel_2                 matlab.ui.control.Label
        window_end_2              matlab.ui.control.NumericEditField
        ApplyButton_2             matlab.ui.control.Button
    end


    properties (Access = public)
        
        data; % ECG Signal
        data2; % ECG Signal after computing
        time_axis % Data length
        freq_axis % Frequency length
        Fs % Sampling frequency
        R % R-points position
        Q % Q-points position
        S % S-points position
        
        filtered_signal_bandpass
        differentiated_signal
        SMW
        
    end

    methods (Access = private)
       
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('.'));
        end

        % Button pushed function: BradyTachycardiaButton
        function B_T(app, event)
            [result, bpm] = Brady_Tachy(app.R, app.Fs);
            
            % Display Result 
            app.ResultLabel.Text = result;
            app.ResultLabel.Visible = 'on';
            
            % Display BPM
            app.BPMLabel.Text = int2str(round(bpm));
            app.BPMLabel.Visible = 'on';
            app.BPMLabel_2.Visible = 'on';
        end

        % Button pushed function: LoadECGSignalButton
        function LoadECGSignalButtonPushed(app, event)
            % Load a file
            [file,path] = uigetfile('*.mat', 'rt');
            signal = load(fullfile(path, file));
            
            % ecg data
            app.data = signal.ecg;
            
            % Sampling frequency
            app.Fs = signal.Fs;
            N = size(app.data,2);
            
            % Data length
            app.time_axis = (1:N)/app.Fs;
            
            % Plot ECG Signal
            plot(app.UIAxes, app.time_axis, app.data);
            
            % Actualize Window value
            app.window_beg.Value = 0;
            app.window_end.Value = app.time_axis(end);
            change_window(app,event);
            
            % Reset labels
            app.BPMLabel.Visible = 'off';
            app.ResultLabel.Visible = 'off';
           
        end

        % Button pushed function: ApplyButton
        function change_window(app, event)
            value1 = app.window_beg.Value;
            value2 = app.window_end.Value;
            if (value2>value1)
                app.UIAxes.XLim = [value1, value2];
                app.window_end.BackgroundColor = [1.00, 1.00, 1.00];
            else
                app.window_end.BackgroundColor = [0.95, 0.38, 0.38];
            end
        end

        % Button pushed function: EctopicbeatButton
        function ectopic(app, event)
            result = Ectopic_beat(app.R, app.Fs);
            if ~isempty(result)
                app.ResultLabel.Text = 'Yes, represented with crosses';
                app.ResultLabel.Visible = 'on';
                plot(app.UIAxes, app.time_axis, app.data2);
                app.UIAxes.NextPlot = 'add';
                plot(app.UIAxes, result/app.Fs, app.data2(result), 'x');
                app.UIAxes.NextPlot = 'replacechildren';
            else
                app.ResultLabel.Text = 'No';
                app.ResultLabel.Visible = 'on';
            end
        end

        % Button pushed function: FibrillationsButton
        function fibril(app, event)
            result = Fibrillation(app.R);
            app.ResultLabel.Text = result;
            app.ResultLabel.Visible = 'on';
        end

        % Button pushed function: FindPQRButton
        function findPQR(app, event)
            
            % Algorythm
            [app.data2, app.R, app.Q, app.S]= Pan_and_Tompkins(app.data,app.Fs);
            
            % Display
            plot(app.UIAxes, app.time_axis, app.data2);
            app.UIAxes.NextPlot = 'add';
            plot(app.UIAxes, app.R./app.Fs, app.data2(app.R),'o');
            plot(app.UIAxes, app.Q./app.Fs, app.data2(app.Q),'o');
            plot(app.UIAxes, app.S./app.Fs, app.data2(app.S),'o');
            app.UIAxes.NextPlot = 'replacechildren';
        end

        % Button pushed function: InverseECGSignalButton
        function inverse(app, event)
            app.data=-app.data;
            
            % Plot ECG Signal
            plot(app.UIAxes, app.time_axis, app.data);
        end

        % Callback function
        function time_axis_beg(app, event)
            
        end

        % Button pushed function: LoadECGSignalButton_2
        function Load_ECG_Advanced(app, event)
            % Load a file
            [file,path] = uigetfile('*.mat', 'rt');
            signal = load(fullfile(path, file));
            
            % ecg data
            app.data = signal.ecg;
            
            % Sampling frequency
            app.Fs = signal.Fs;
            N = size(app.data,2);
            
            % Data length
            app.time_axis = (1:N)/app.Fs;
            app.freq_axis = linspace(-app.Fs/2, app.Fs/2, N);
        end

        % Button pushed function: SignalSpectrumButton
        function plot_TF(app, event)
            % Plot ECG Signal
            plot(app.UIAxes_2, app.freq_axis, fftshift(abs(fft(app.data))));
            app.UIAxes_2.Title.String = 'Spectrum of ECG Signal';
            app.UIAxes_2.XLabel.String = 'Frequency (Hz)';
            app.UIAxes_2.YLabel.String = 'Absolute value of Fourier Transform';
        end

        % Button pushed function: BandPassButton
        function band_pass(app, event)
            [ app.filtered_signal_bandpass, TF_filtered_signal_bandpass ] = band_pass(app.data);
            
            % Plot
            plot(app.UIAxes_2, app.freq_axis, TF_filtered_signal_bandpass);
            app.UIAxes_2.Title.String = 'Spectrum of Band-Pass Signal';
        end

        % Button pushed function: DerivativeButton
        function derivative(app, event)
            [app.differentiated_signal, TF_differentiated_signal] = derivative(app.filtered_signal_bandpass, app.Fs);
            
            % Plot
            plot(app.UIAxes_2, app.freq_axis, TF_differentiated_signal);
            app.UIAxes_2.Title.String = 'Spectrum of differentiated Signal';
        end

        % Button pushed function: MWIButton
        function MWI(app, event)
            [app.SMW, app.data2] = MWI(app.differentiated_signal, app.data, app.Fs);
            
            % Plot
            plot(app.UIAxes_2, app.SMW);
            app.UIAxes_2.NextPlot = 'add';
            plot(app.UIAxes_2, app.data2);
            app.UIAxes_2.Title.String = 'ECG Signal with square signal';
            app.UIAxes_2.XLabel.String = 'Samples';
            app.UIAxes_2.YLabel.String = 'Magnitude';
            app.UIAxes_2.NextPlot = 'replacechildren';
            
            
        end

        % Button pushed function: InverseECGSignalButton_2
        function inverse2(app, event)
            app.data2=-app.data2;
            
            % Plot
            plot(app.UIAxes_2, app.data2);
            app.UIAxes_2.NextPlot = 'add';
            plot(app.UIAxes_2, app.SMW);
            app.UIAxes_2.Title.String = 'ECG Signal with square signal';
            app.UIAxes_2.XLabel.String = 'Samples';
            app.UIAxes_2.YLabel.String = 'Magnitude';
            app.UIAxes_2.NextPlot = 'replacechildren';
        end

        % Button pushed function: ApplyButton_2
        function change_window_2(app, event)
            value1 = app.window_beg_2.Value;
            value2 = app.window_end_2.Value;
            if (value2>value1)
                app.UIAxes_2.XLim = [value1, value2];
                app.window_end_2.BackgroundColor = [1.00, 1.00, 1.00];
            else
                app.window_end_2.BackgroundColor = [0.95, 0.38, 0.38];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicFeaturesTab = uitab(app.TabGroup);
            app.BasicFeaturesTab.Title = 'Basic Features';
            app.UIAxes = uiaxes(app.BasicFeaturesTab);
            title(app.UIAxes, 'ECG Signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Magnitude')
            app.Label = uilabel(app.BasicFeaturesTab);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.LoadECGSignalButton = uibutton(app.BasicFeaturesTab, 'push');
            app.LoadECGSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadECGSignalButtonPushed, true);
            app.LoadECGSignalButton.Text = 'Load ECG Signal';
            app.FindPQRButton = uibutton(app.BasicFeaturesTab, 'push');
            app.FindPQRButton.ButtonPushedFcn = createCallbackFcn(app, @findPQR, true);
            app.FindPQRButton.Text = 'Find PQR';
            app.MICAProjectLabel = uilabel(app.BasicFeaturesTab);
            app.MICAProjectLabel.Text = 'MICA Project';
            app.TimeWindowPanel = uipanel(app.BasicFeaturesTab);
            app.TimeWindowPanel.Title = 'Time Window';
            app.window_beg = uieditfield(app.TimeWindowPanel, 'numeric');
            app.window_beg.Limits = [0 Inf];
            app.toLabel = uilabel(app.TimeWindowPanel);
            app.toLabel.Text = 'to';
            app.window_end = uieditfield(app.TimeWindowPanel, 'numeric');
            app.ApplyButton = uibutton(app.TimeWindowPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @change_window, true);
            app.ApplyButton.Text = 'Apply';
            app.BradyTachycardiaButton = uibutton(app.BasicFeaturesTab, 'push');
            app.BradyTachycardiaButton.ButtonPushedFcn = createCallbackFcn(app, @B_T, true);
            app.BradyTachycardiaButton.Text = 'Brady/Tachycardia';
            app.BPMLabel = uilabel(app.BasicFeaturesTab);
            app.BPMLabel.Visible = 'off';
            app.BPMLabel.Text = 'BPM';
            app.EctopicbeatButton = uibutton(app.BasicFeaturesTab, 'push');
            app.EctopicbeatButton.ButtonPushedFcn = createCallbackFcn(app, @ectopic, true);
            app.EctopicbeatButton.Text = 'Ectopic beat';
            app.FibrillationsButton = uibutton(app.BasicFeaturesTab, 'push');
            app.FibrillationsButton.ButtonPushedFcn = createCallbackFcn(app, @fibril, true);
            app.FibrillationsButton.Text = 'Fibrillations';
            app.ResultLabel = uilabel(app.BasicFeaturesTab);
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'Result';
            app.BPMLabel_2 = uilabel(app.BasicFeaturesTab);
            app.BPMLabel_2.Visible = 'off';
            app.BPMLabel_2.Text = 'BPM';
            app.InverseECGSignalButton = uibutton(app.BasicFeaturesTab, 'push');
            app.InverseECGSignalButton.ButtonPushedFcn = createCallbackFcn(app, @inverse, true);
            app.InverseECGSignalButton.Text = 'Inverse ECG Signal';
            app.AdvancedFeaturesTab = uitab(app.TabGroup);
            app.AdvancedFeaturesTab.Title = 'Advanced Features';
            app.Label_2 = uilabel(app.AdvancedFeaturesTab);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.MICAProjectLabel_2 = uilabel(app.AdvancedFeaturesTab);
            app.MICAProjectLabel_2.Text = 'MICA Project';
            app.LoadECGSignalButton_2 = uibutton(app.AdvancedFeaturesTab, 'push');
            app.LoadECGSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @Load_ECG_Advanced, true);
            app.LoadECGSignalButton_2.Text = 'Load ECG Signal';
            app.BandPassButton = uibutton(app.AdvancedFeaturesTab, 'push');
            app.BandPassButton.ButtonPushedFcn = createCallbackFcn(app, @band_pass, true);
            app.BandPassButton.Text = 'Band-Pass';
            app.DerivativeButton = uibutton(app.AdvancedFeaturesTab, 'push');
            app.DerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @derivative, true);
            app.DerivativeButton.Text = 'Derivative';
            app.MWIButton = uibutton(app.AdvancedFeaturesTab, 'push');
            app.MWIButton.ButtonPushedFcn = createCallbackFcn(app, @MWI, true);
            app.MWIButton.Text = 'MWI';
            app.InverseECGSignalButton_2 = uibutton(app.AdvancedFeaturesTab, 'push');
            app.InverseECGSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @inverse2, true);
            app.InverseECGSignalButton_2.Text = 'Inverse ECG Signal';
            app.UIAxes_2 = uiaxes(app.AdvancedFeaturesTab);
            title(app.UIAxes_2, 'Spectrum of ECG Signal ')
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Absolute value of Fourier Transform')
            app.SignalSpectrumButton = uibutton(app.AdvancedFeaturesTab, 'push');
            app.SignalSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @plot_TF, true);
            app.SignalSpectrumButton.Text = 'Signal Spectrum';
            app.SampleWindowPanel = uipanel(app.AdvancedFeaturesTab);
            app.SampleWindowPanel.Title = 'Sample Window';
            app.window_beg_2 = uieditfield(app.SampleWindowPanel, 'numeric');
            app.window_beg_2.Limits = [0 Inf];
            app.toLabel_2 = uilabel(app.SampleWindowPanel);
            app.toLabel_2.Text = 'to';
            app.window_end_2 = uieditfield(app.SampleWindowPanel, 'numeric');
            app.ApplyButton_2 = uibutton(app.SampleWindowPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @change_window_2, true);
            app.ApplyButton_2.Text = 'Apply';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end